classdef a13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SpeedupCheckBox  matlab.ui.control.CheckBox
        GAMEOVERLabel    matlab.ui.control.Label
        StartgameButton  matlab.ui.control.Button
        NumberofturnstogobackifyoudieSpinner  matlab.ui.control.Spinner
        NumberofturnstogobackifyoudieSpinnerLabel  matlab.ui.control.Label
        ScoreLabel       matlab.ui.control.Label
        Title            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
%         RandTimer          % Timer object
%         PlotLine           % Line object
        PlotScreen         % Screen object
        KeyPress           % User input object
%         PrevFrames         % Saves of the previous n frames
        Codes
        Disp_datas
        is
        rel_bases
        next_inp
        speedup
        turnNumber
    end

    methods (Access = private)   
 

        function [screen,score] = drawScreen(app,out)
            % empty: white
            % wall: black
            % block: red
            % paddle: blue
            % ball: green
            screen = nan(25,35,3);
            score = 0;
            for ii=1:numel(out)/3
                instruction = out((ii-1)*3+1:ii*3);
                if instruction(1) == -1 && instruction(2) == 0
                    score = instruction(3);
                elseif ismember(instruction(3),[0 1 2 3 4])
                    switch instruction(3)
                        case 0
                            c1 = 1; c2 = 1; c3 = 1;
                        case 1
                            c1 = 0; c2 = 0; c3 = 0;
                        case 2
                            c1 = 1; c2 = 0; c3 = 0;
                        case 3
                            c1 = 0; c2 = 0; c3 = 1;
                        case 4
                            c1 = 0; c2 = 1; c3 = 0;
                    end
                    screen(instruction(2)+1,instruction(1)+1,1) = c1;
                    screen(instruction(2)+1,instruction(1)+1,2) = c2;
                    screen(instruction(2)+1,instruction(1)+1,3) = c3;
                end
            end
        end

        function testFun(app, event)

            
%             nn = 1;
            first = true;
            while true
%             for nn=1:maxframe
%                 app.TurnNoLabel.Text = num2str(firstNan - frame-start_offset);
                code = app.Codes{end};
                i = app.is{end};
                rel_base = app.rel_bases{end};
                [out, code] = run_intcode(code, app.next_inp, app.next_inp, i, rel_base, true, true, true);
                if iscell(out)
                    i = out{2};
                    app.is{1} = i;
                    app.is = circshift(app.is,-1);
                    rel_base = out{3};
                    app.rel_bases{1} = rel_base;
                    app.rel_bases = circshift(app.rel_bases,-1);
                    disp_data = [app.Disp_datas{end} out{1}];
                    app.Disp_datas{1} = disp_data;
                    app.Disp_datas = circshift(app.Disp_datas,-1);
                    app.Codes{1} = code;
                    app.Codes = circshift(app.Codes,-1);
                end
                [screen, score] = app.drawScreen(disp_data);

                app.PlotScreen = image(app.UIAxes,screen);
                %drawnow

                app.ScoreLabel.Text = num2str(score);
                if first
                    first = false;
                    prev_screen = screen;
                elseif screen == prev_screen
                    if app.turnNumber <= app.NumberofturnstogobackifyoudieSpinner.Value
                        % start from the beginning
                        app.GAMEOVERLabel.Visible = true;
                        break
                    else
                        % start from Value-turns ago
                        app.is = circshift(app.is,app.NumberofturnstogobackifyoudieSpinner.Value);
                        app.rel_bases = circshift(app.rel_bases,app.NumberofturnstogobackifyoudieSpinner.Value);
                        app.Disp_datas = circshift(app.Disp_datas,app.NumberofturnstogobackifyoudieSpinner.Value);
                        app.Codes = circshift(app.Codes,app.NumberofturnstogobackifyoudieSpinner.Value);
                        app.turnNumber = app.turnNumber - app.NumberofturnstogobackifyoudieSpinner.Value;
                    end
%                     close all
%                     start_frame = frame;
%                     save('./a13_input_list.mat','input_list','disp_data','code','out','next_inp','i','rel_base','use_input','start_frame')
%                     break
                end
                prev_screen = screen;
                use_input = true;


                if app.speedup == 0
                    pause(0.5)
                else
                    test = 2;
                end
                if app.KeyPress.left == true
                    app.next_inp = -1;
                elseif app.KeyPress.right == true
                    app.next_inp = 1;
                else
                    app.next_inp = 0;
                end
%                 pause(delta_t)
                app.turnNumber = app.turnNumber + 1;
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init buttons
%             app.StartfromthebeginningCheckBox.Value = 0;
            app.NumberofturnstogobackifyoudieSpinner.Enable = 1;
            app.NumberofturnstogobackifyoudieSpinnerLabel.Enable = 1;
            app.GAMEOVERLabel.Visible = false;


%             app.PrevFrames = [];
            app.Codes = cell(100,1);
            app.Disp_datas = cell(100,1);
            app.is = cell(100,1);
            app.rel_bases = cell(100,1);
            app.turnNumber = 1;
            app.speedup = 0;
            % Read the data and create the first plot
            code = readmatrix("input_13.txt");
            [out, code] = run_intcode(code, 0, 0, 1, 0, true);
            code(1) = 2;
            i = 1;
            rel_base = 0;
            app.next_inp = 0;
            disp_data = [];
            use_input = false;
            first = true;
            prev_screen = []; 
            delta_t = 0.5;
%                 input_list = nan(maxframe,1);
%             start_frame = 1;
%             save_offset = app.NumberofturnstogobackifyoudieSpinner.Value;
%             save_counter = 0;

            app.Codes{1} = code;
            app.Codes = circshift(app.Codes,-1);
            app.Disp_datas{1} = disp_data;
            app.Disp_datas = circshift(app.Disp_datas,-1);
            app.is{1} = i;
            app.is = circshift(app.is,-1);
            app.rel_bases{1} = rel_base;
            app.rel_bases = circshift(app.rel_bases,-1);

            [screen, score] = app.drawScreen(out);
    
            % Plot to app
            app.PlotScreen = image(app.UIAxes,screen);

            % Update score
            app.ScoreLabel.Text = num2str(score);

            % Init keypress
            app.KeyPress.left = false;
            app.KeyPress.right = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
        end

        % Key press function: UIFigure
        function KeyPressFcn(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                app.KeyPress.left = true;                
            elseif strcmp(key,'rightarrow')
                app.KeyPress.right = true;  
            end
        end

        % Key release function: UIFigure
        function KeyReleaseFcn(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                app.KeyPress.left = false;  
            elseif strcmp(key,'rightarrow')
                app.KeyPress.right = false;  
            end
        end

        % Callback function
        function startFromBegCallback(app, event)

        end

        % Button pushed function: StartgameButton
        function startCallback(app, event)

            testFun(app, event)
%             code = readmatrix("input_13.txt");
%             [out, code] = run_intcode(code, 0, 0, 1, 0, true);
%             code(1) = 2;
%             i = 1;
%             rel_base = 0;
%             next_inp = 0;
%             disp_data = [];
%             use_input = false;
%             first = true;
%             prev_screen = []; 
%             delta_t = 0.5;
% %                 input_list = nan(maxframe,1);
% %             start_frame = 1;
% %             save_offset = app.NumberofturnstogobackifyoudieSpinner.Value;
% %             save_counter = 0;
% 
%             app.Codes{1} = code;
%             app.Codes = circshift(app.Codes,-1);
%             app.Disp_datas{1} = disp_data;
%             app.Disp_datas = circshift(app.Disp_datas,-1);
%             app.is{1} = i;
%             app.is = circshift(app.is,-1);
%             app.rel_bases{1} = rel_base;
%             app.rel_bases = circshift(app.rel_bases,-1);
%          
% 
%             [screen, score] = app.drawScreen(out);
%     
%             % Plot to app
%             app.PlotScreen = image(app.UIAxes,screen);
% 
%             % Update score
%             app.ScoreLabel.Text = num2str(score);
% 
%             % Init keypress
%             app.KeyPress.left = false;
%             app.KeyPress.right = false;
% 
% %             save_offset = app.NumberofturnstogobackifyoudieSpinner.Value;
% %             save_offset = start_frame + save_offset;
%             nn = 1;
%             while true
% %             for nn=1:maxframe
% %                 app.TurnNoLabel.Text = num2str(firstNan - frame-start_offset);
%                 code = app.Codes{end};
%                 i = app.is{end};
%                 rel_base = app.rel_bases{end};
%                 [out, code] = run_intcode(code, next_inp, next_inp, i, rel_base, true, true, use_input);
%                 if iscell(out)
%                     i = out{2};
%                     app.is{1} = i;
%                     app.is = circshift(app.is,-1);
%                     rel_base = out{3};
%                     app.rel_bases{1} = rel_base;
%                     app.rel_bases = circshift(app.rel_bases,-1);
%                     disp_data = [app.Disp_datas{end} out{1}];
%                     app.Disp_datas{1} = disp_data;
%                     app.Disp_datas = circshift(app.Disp_datas,-1);
%                     app.Codes{1} = code;
%                     app.Codes = circshift(app.Codes,-1);
%                 end
%                 [screen, score] = app.drawScreen(disp_data);
% 
%                 app.PlotScreen = image(app.UIAxes,screen);
%                 %drawnow
% 
%                 app.ScoreLabel.Text = num2str(score);
%                 if first
%                     first = false;
%                     prev_screen = screen;
%                 elseif screen == prev_screen
%                     if nn <= app.NumberofturnstogobackifyoudieSpinner.Value
%                         % start from the beginning
%                         app.GAMEOVERLabel.Visible = true;
%                         break
%                     else
%                         % start from Value-turns ago
%                         app.is = circshift(app.is,app.NumberofturnstogobackifyoudieSpinner.Value);
%                         app.rel_bases = circshift(app.rel_bases,app.NumberofturnstogobackifyoudieSpinner.Value);
%                         app.Disp_datas = circshift(app.Disp_datas,app.NumberofturnstogobackifyoudieSpinner.Value);
%                         app.Codes = circshift(app.Codes,app.NumberofturnstogobackifyoudieSpinner.Value);
%                         nn = nn - app.NumberofturnstogobackifyoudieSpinner.Value;
%                     end
% %                     close all
% %                     start_frame = frame;
% %                     save('./a13_input_list.mat','input_list','disp_data','code','out','next_inp','i','rel_base','use_input','start_frame')
% %                     break
%                 end
%                 prev_screen = screen;
%                 use_input = true;
% 
% 
%                 if app.speedup == 0
%                     pause(delta_t)
%                 else
%                     test = 2;
%                 end
%                 if app.KeyPress.left == true
%                     next_inp = -1;
%                 elseif app.KeyPress.right == true
%                     next_inp = 1;
%                 else
%                     next_inp = 0;
%                 end
% %                 pause(delta_t)
%                 nn = nn + 1;
%             end
            
        end

        % Value changed function: SpeedupCheckBox
        function SpeedUp(app, event)
            app.speedup = app.SpeedupCheckBox.Value;
            testFun(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '2019 Day 13';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressFcn, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @KeyReleaseFcn, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Score: ';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score';
            app.NumberofturnstogobackifyoudieSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofturnstogobackifyoudieSpinnerLabel.Text = 'Number of turns to go back if you die';
            app.NumberofturnstogobackifyoudieSpinner = uispinner(app.UIFigure);
            app.NumberofturnstogobackifyoudieSpinner.Value = 15;
            app.StartgameButton = uibutton(app.UIFigure, 'push');
            app.StartgameButton.ButtonPushedFcn = createCallbackFcn(app, @startCallback, true);
            app.StartgameButton.Text = 'Start game';
            app.GAMEOVERLabel = uilabel(app.UIFigure);
            app.GAMEOVERLabel.Visible = 'off';
            app.GAMEOVERLabel.Text = 'GAME OVER';
            app.SpeedupCheckBox = uicheckbox(app.UIFigure);
            app.SpeedupCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeedUp, true);
            app.SpeedupCheckBox.Text = 'Speed up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = a13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end